classdef TunnelApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AirspeedUnitsDropDown           matlab.ui.control.DropDown
        AirspeedUnitsDropDownLabel      matlab.ui.control.Label
        PressureUnitsDropDown           matlab.ui.control.DropDown
        PressureUnitsDropDownLabel      matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        SerialPortDropDown              matlab.ui.control.DropDown
        SerialPortLabel                 matlab.ui.control.Label
        ConnectionLamp                  matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        DisconnectButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        DynamicPressureGauge            matlab.ui.control.LinearGauge
        DynamicPressurePaGaugeLabel     matlab.ui.control.Label
        AoAPortPressureDifferenceGauge  matlab.ui.control.LinearGauge
        PortPressureDifferencePaLabel   matlab.ui.control.Label
        AngleofAttackGauge              matlab.ui.control.SemicircularGauge
        AngleofAttackcircLabel          matlab.ui.control.Label
        MeasuredAirspeedGauge           matlab.ui.control.Gauge
        MeasuredAirspeedmsGaugeLabel    matlab.ui.control.Label
        SpeedSelectionKnob              matlab.ui.control.Knob
        SpeedSelectionmsKnobLabel       matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        SETSPEEDButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        FreezeButton_aoa                matlab.ui.control.StateButton
        FreezeButton_asp                matlab.ui.control.StateButton
        ClearPlotButton_2               matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        AoaPlot                         matlab.ui.control.UIAxes
        AspdPlot                        matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
    end


    properties (Access = private)
        % Defines set of global constants
        TIMER_OBJ;              % Timer that executes readData()
        TIME_INTERVAL = 0.05;   % Seconds. Interval for the TIMER_OBJ
        CONNECTED;              % Flag for whether or not the app is connected to a serial port
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        MAX_VECTOR_LENGTH = 1000;   % Defines the length of uutSpeeds and aoaPres used for plotting
        SERIAL_PORT;        % Serial port object
        PA2PSI = 1/6895;    % Conversion factor (Pascal to psi)
        MPS2FPS = 3.281;    % Conversion factor (meters/sec to ft/sec)
        MPS2MPH = 2.23694;  % Conversion factor (meters/sec to mi/hr)
        MPS2KTS = 1.94384;  % Conversion factor (meters/sec to knots)
        
        conversionFactorPressure;
        conversionFactorAirspeed;

        % Plot objects
        pltAspdLeft;               
        pltAspdRight;
        pltAoaLeft;               
        pltAoaRight;

        % Plot properties
        pltMaxDynamicPressure = 1500;       % Pascals
        pltMaxAoaPressure = 500;            % Pascals
    end
    
    properties (Access = public)
        uutSpeeds = [];    % Vector of airspeed values measured by pitot probe with alpha ports
        uutPres = [];
        aoaPres = [];      % Vector of measured pressure differences between alpha ports
        aoaVec = [];

        uutSpeedsConverted = [];    % Vector of airspeed values measured by pitot probe with alpha ports
        uutPresConverted = [];
        aoaPresConverted = [];      % Vector of measured pressure differences between alpha ports


    end
   
    
    methods (Access = private)
        
        function readData(app, ~, ~)
            numLinesToRead = 12;
            flush(app.SERIAL_PORT);      % Clear unread messages
            data = zeros(numLinesToRead, 3);
            while app.SERIAL_PORT.NumBytesAvailable < 4    % Wait until a full message is ready
            end
        
            for i = 1:numLinesToRead
                if i == 1
                    useless = readline(app.SERIAL_PORT);   % Dump the first line into an unused variable
                end
                string     = readline(app.SERIAL_PORT);
                data(i, :) = str2double(strsplit(string, "\t"));
                
            end
            % Parse data
            aoa_dat = mean(data(data(:, 1)==1, :), 1);
            ref_dat = mean(data(data(:, 1)==3, :), 1);
            uut_dat = mean(data(data(:, 1)==4, :), 1);
            % Update speed and aoa vectors
            app.uutSpeeds      = circshift(app.uutSpeeds, -1);
            app.uutSpeeds(end) = uut_dat(3);
            app.uutPres        = circshift(app.uutPres, -1);
            app.uutPres(end)   = uut_dat(2);
            app.aoaPres        = circshift(app.aoaPres, -1);
            app.aoaPres(end)   = aoa_dat(2);
            app.aoaVec         = circshift(app.aoaVec, -1);

            app.uutSpeedsConverted      = circshift(app.uutSpeeds, -1);
            app.uutSpeedsConverted(end) = uut_dat(3)*app.conversionFactorAirspeed;
            app.uutPresConverted        = circshift(app.uutPres, -1);
            app.uutPresConverted(end)   = uut_dat(2)*app.conversionFactorPressure;
            app.aoaPresConverted        = circshift(app.aoaPres, -1);
            app.aoaPresConverted(end)   = aoa_dat(2)*app.conversionFactorPressure;
            
            % Update Gauges
            app.DynamicPressureGauge.Value  = ref_dat(2)*app.conversionFactorPressure;
            app.MeasuredAirspeedGauge.Value = ref_dat(3)*app.conversionFactorAirspeed;
            app.AoAPortPressureDifferenceGauge.Value = aoa_dat(2)*app.conversionFactorPressure;
            app.aoaVec(end) = interpAoa(aoa_dat(2), ref_dat(2));
            app.AngleofAttackGauge.Value = app.aoaVec(end);

            % Update plots
            if ~app.FreezeButton_asp.Value
            refreshdata(app.pltAspdLeft, 'caller');
            refreshdata(app.pltAspdRight, 'caller');
            end
            if ~app.FreezeButton_aoa.Value
            refreshdata(app.pltAoaLeft, 'caller');
            refreshdata(app.pltAoaRight, 'caller');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize vectors
            app.uutSpeeds = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaPres = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaVec = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uutPres = zeros(1, app.MAX_VECTOR_LENGTH);

            app.uutSpeedsConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaPresConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uutPresConverted = zeros(1, app.MAX_VECTOR_LENGTH);
            
            % Initialize the plots
            yyaxis(app.AspdPlot, "left")
            app.pltAspdLeft = plot(app.AspdPlot, 1:app.MAX_VECTOR_LENGTH, app.uutSpeeds);
            app.pltAspdLeft.YDataSource = 'app.uutSpeedsConverted';
            yyaxis(app.AspdPlot, "right")
            app.pltAspdRight = plot(app.AspdPlot, 1:app.MAX_VECTOR_LENGTH, app.uutPres);
            app.pltAspdRight.YDataSource = 'app.uutPresConverted';
            app.AspdPlot.YLabel.String = 'Pressure (Pa)';
            ylim(app.AspdPlot, [0, 1500])
            yticks(app.AspdPlot, 0:250:1500)

            yyaxis(app.AoaPlot, "left")
            title(app.AoaPlot, "UUT α-Port Pressure Difference & Angle of Attack")
            app.pltAoaLeft = plot(app.AoaPlot, 1:app.MAX_VECTOR_LENGTH, app.aoaPres);
            app.pltAoaLeft.YDataSource = 'app.aoaPresConverted';
            yyaxis(app.AoaPlot, "right")
            app.pltAoaRight = plot(app.AoaPlot, 1:app.MAX_VECTOR_LENGTH, app.aoaVec);
            app.pltAoaRight.YDataSource = 'app.aoaVec';
            app.AoaPlot.YLabel.String = 'Angle of Attack (\circ)';
            ylim(app.AoaPlot, [-40, 40])
            

            for i = [app.pltAspdLeft, app.pltAspdRight, app.pltAoaLeft, app.pltAoaRight]
                lineWidth = 2;
                set(i, 'LineWidth', lineWidth);
            end
            
            % Create serial port list
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;

            % Initialize, but don't start, the timer
            app.TIMER_OBJ = timer;
            app.TIMER_OBJ.TimerFcn = @app.readData;
            app.TIMER_OBJ.ExecutionMode = 'fixedRate';
            app.TIMER_OBJ.Period = app.TIME_INTERVAL;
            app.TIMER_OBJ.BusyMode = 'drop';

            app.conversionFactorPressure = 1;
            app.conversionFactorAirspeed = 1;


        end

        % Button pushed function: SETSPEEDButton
        function SETSPEEDButtonPushed(app, event)
            if app.CONNECTED
                pwrSetting = ((app.SpeedSelectionKnob.Value - 4.542)/0.3725)/app.conversionFactorAirspeed;
                writeline(app.SERIAL_PORT, num2str(pwrSetting));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.CONNECTED
                writeline(app.SERIAL_PORT, "0");
                app.SpeedSelectionKnob.Value = 0;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.SERIAL_PORT)
                app.SERIAL_PORT = serialport(app.SerialPortDropDown.Value, 9600);
                app.CONNECTED = 1;
                app.ConnectionLamp.Color = app.GREEN_COLOR;
                start(app.TIMER_OBJ)
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.SERIAL_PORT = [];
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;
            stop(app.TIMER_OBJ);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            app.uutSpeeds = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uutPres = zeros(1, app.MAX_VECTOR_LENGTH);
        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            app.aoaPres = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoaVec = zeros(1, app.MAX_VECTOR_LENGTH);
        end

        % Value changed function: PressureUnitsDropDown
        function PressureUnitsDropDownValueChanged(app, event)
            value = app.PressureUnitsDropDown.Value;
            if strcmp(value, "Pascal")
                app.conversionFactorPressure = 1;
                % Gauges
                % Dynamic pressure gauge
                app.DynamicPressureGauge.Limits = [0, 1500];
                app.DynamicPressureGauge.MajorTicks = 0:250:1500;
                app.DynamicPressurePaGaugeLabel.Text = "Dynamic Pressure (Pa)";

                % Aoa pressure gauge
                app.AoAPortPressureDifferenceGauge.Limits = [-500, 500];
                app.AoAPortPressureDifferenceGauge.MajorTicks = -500:200:500;
                app.AoAPortPressureDifferenceGauge.MajorTickLabelsMode = 'auto';
                app.PortPressureDifferencePaLabel.Text = "α-Port Pressure Difference (Pa)";


                % Plots
                yyaxis(app.AoaPlot, "left")
                app.AoaPlot.YLabel.String = "Pressure (Pa)";
                ylim(app.AoaPlot, [-500, 500])
                yticks(app.AoaPlot, -500:100:500)
                yyaxis(app.AspdPlot, "right")
                app.AspdPlot.YLabel.String = "Pressure (Pa)";
                ylim(app.AspdPlot, [0, 1500])
                yticks(app.AspdPlot, 0:250:1500);
            elseif strcmp(value, "psi")
                app.conversionFactorPressure = app.PA2PSI;
                % Gauges
                % Dynamic pressure gauge
                app.DynamicPressureGauge.Limits = [0, 0.220];
                app.DynamicPressureGauge.MajorTicks = 0:0.055:0.220;
                app.DynamicPressurePaGaugeLabel.Text = "Dynamic Pressure (psi)";

                % Aoa pressure gauge
                app.AoAPortPressureDifferenceGauge.Limits = [-0.0725, 0.0725];
                app.AoAPortPressureDifferenceGauge.MajorTicks = -0.0725:0.0145:0.0725;
                app.AoAPortPressureDifferenceGauge.MajorTickLabels = {'-.0725','', '', '-.0290', '', '0', '', '.0290', '', '', '.0725'};
                app.PortPressureDifferencePaLabel.Text = "α-Port Pressure Difference (psi)";

                % Plots
                yyaxis(app.AoaPlot, "left")
                app.AoaPlot.YLabel.String = "Pressure (psi)";
                ylim(app.AoaPlot, [-0.0725, 0.0725])
                yticks(app.AoaPlot, -0.0725:0.0145:0.0725)
                yyaxis(app.AspdPlot, "right")
                app.AspdPlot.YLabel.String = "Pressure (psi)";
                ylim(app.AspdPlot, [0, 0.220])
                yticks(app.AspdPlot, 0:0.02:0.220);
            end
        end

        % Value changed function: AirspeedUnitsDropDown
        function AirspeedUnitsDropDownValueChanged(app, event)
            value = app.AirspeedUnitsDropDown.Value;
            if strcmp(value, "m/s")
                app.conversionFactorAirspeed = 1;
                % Gauges
                % Speed selection knob
                app.SpeedSelectionKnob.Limits = [0, 35];
                app.SpeedSelectionKnob.MajorTicks = 0:5:35;
                app.SpeedSelectionKnob.MinorTicks = 0:35;
                app.SpeedSelectionmsKnobLabel.Text = "Speed Selection (m/s)";
                % Measured airspeed (ref)
                app.MeasuredAirspeedGauge.Limits = [0, 40];
                app.MeasuredAirspeedGauge.MajorTicks = 0:5:40;
                app.MeasuredAirspeedGauge.MinorTicks = 0:40;
                app.MeasuredAirspeedmsGaugeLabel.Text = "Measured Airspeed (m/s)";
                app.MeasuredAirspeedGauge.ScaleColorLimits = [20, 35];
                % Plots
                % Measured airspeed (UUT)
                yyaxis(app.AspdPlot, "left")
                app.AspdPlot.YLabel.String = "Airspeed (m/s)";
                ylim(app.AspdPlot, [0, 45])
                yticks(app.AspdPlot, 0:5:45);

            elseif strcmp(value, "ft/s")
                app.conversionFactorAirspeed = app.MPS2FPS;
                % Gauges
                % Speed selection knob
                app.SpeedSelectionKnob.Limits = [0, 120];
                app.SpeedSelectionKnob.MajorTicks = 0:15:120;
                app.SpeedSelectionKnob.MinorTicks = 0:5:120;
                app.SpeedSelectionmsKnobLabel.Text = "Speed Selection (ft/s)";
                % Measured airspeed (ref)
                app.MeasuredAirspeedGauge.Limits = [0, 125];
                app.MeasuredAirspeedGauge.MajorTicks = 0:25:125;
                app.MeasuredAirspeedGauge.MinorTicks = 0:5:125;
                app.MeasuredAirspeedmsGaugeLabel.Text = "Measured Airspeed (ft/s)";
                app.MeasuredAirspeedGauge.ScaleColorLimits = [20, 35]*app.conversionFactorAirspeed;
                % Plots
                % Measured airspeed (UUT)
                yyaxis(app.AspdPlot, "left")
                app.AspdPlot.YLabel.String = "Airspeed (ft/s)";
                ylim(app.AspdPlot, [0, 150])
                yticks(app.AspdPlot, 0:15:150);

            elseif strcmp(value, "mi/hr")
                app.conversionFactorAirspeed = app.MPS2MPH;
                % Gauges
                % Speed selection knob
                app.SpeedSelectionKnob.Limits = [0, 80];
                app.SpeedSelectionKnob.MajorTicks = 0:10:80;
                app.SpeedSelectionKnob.MinorTicks = 0:2:80;
                app.SpeedSelectionmsKnobLabel.Text = "Speed Selection (mi/hr)";
                % Measured airspeed (ref)
                app.MeasuredAirspeedGauge.Limits = [0, 90];
                app.MeasuredAirspeedGauge.MajorTicks = 0:10:90;
                app.MeasuredAirspeedGauge.MinorTicks = 0:2:90;
                app.MeasuredAirspeedmsGaugeLabel.Text = "Measured Airspeed (mi/hr)";
                app.MeasuredAirspeedGauge.ScaleColorLimits = [20, 35]*app.conversionFactorAirspeed;
                % Plots
                % Measured airspeed (UUT)
                yyaxis(app.AspdPlot, "left")
                app.AspdPlot.YLabel.String = "Airspeed (mi/hr)";
                ylim(app.AspdPlot, [0, 100])
                yticks(app.AspdPlot, 0:10:100);

            elseif strcmp(value, "kts")
                app.conversionFactorAirspeed = app.MPS2KTS;
                % Gauges
                % Speed selection knob
                app.SpeedSelectionKnob.Limits = [0, 70];
                app.SpeedSelectionKnob.MajorTicks = 0:10:70;
                app.SpeedSelectionKnob.MinorTicks = 0:2:70;
                app.SpeedSelectionmsKnobLabel.Text = "Speed Selection (kts)";
                % Measured airspeed (ref)
                app.MeasuredAirspeedGauge.Limits = [0, 80];
                app.MeasuredAirspeedGauge.MajorTicks = 0:10:80;
                app.MeasuredAirspeedGauge.MinorTicks = 0:2:80;
                app.MeasuredAirspeedmsGaugeLabel.Text = "Measured Airspeed (kts)";
                app.MeasuredAirspeedGauge.ScaleColorLimits = [20, 35]*app.conversionFactorAirspeed;
                % Plots
                % Measured airspeed (UUT)
                yyaxis(app.AspdPlot, "left")
                app.AspdPlot.YLabel.String = "Airspeed (kts)";
                ylim(app.AspdPlot, [0, 90])
                yticks(app.AspdPlot, 0:10:90);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.AspdPlot = uiaxes(app.PlotsTab);
            title(app.AspdPlot, 'UUT Airspeed & Dynamic Pressure')
            ylabel(app.AspdPlot, 'Airspeed (m/s)')
            zlabel(app.AspdPlot, 'Z')
            app.AspdPlot.XLim = [0 1000];
            app.AspdPlot.YLim = [0 45];
            app.AspdPlot.XTickLabel = '';
            app.AoaPlot = uiaxes(app.PlotsTab);
            title(app.AoaPlot, 'UUT \alpha-Port Pressure Difference')
            ylabel(app.AoaPlot, 'Pressure (Pa)')
            zlabel(app.AoaPlot, 'Z')
            app.AoaPlot.XLim = [0 1000];
            app.AoaPlot.YLim = [-500 500];
            app.AoaPlot.XTickLabel = '';
            app.AoaPlot.YTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.ClearPlotButton = uibutton(app.PlotsTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ClearPlotButton_2 = uibutton(app.PlotsTab, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.FreezeButton_asp = uibutton(app.PlotsTab, 'state');
            app.FreezeButton_asp.Text = 'Freeze';
            app.FreezeButton_aoa = uibutton(app.PlotsTab, 'state');
            app.FreezeButton_aoa.Text = 'Freeze';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = 'helpText.html';
            app.SETSPEEDButton = uibutton(app.UIFigure, 'push');
            app.SETSPEEDButton.ButtonPushedFcn = createCallbackFcn(app, @SETSPEEDButtonPushed, true);
            app.SETSPEEDButton.Text = 'SET SPEED';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SpeedSelectionmsKnobLabel = uilabel(app.UIFigure);
            app.SpeedSelectionmsKnobLabel.Text = 'Speed Selection (m/s)';
            app.SpeedSelectionKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedSelectionKnob.Limits = [0 35];
            app.SpeedSelectionKnob.MajorTicks = [0 5 10 15 20 25 30 35];
            app.MeasuredAirspeedmsGaugeLabel = uilabel(app.UIFigure);
            app.MeasuredAirspeedmsGaugeLabel.Text = 'Measured Airspeed (m/s)';
            app.MeasuredAirspeedGauge = uigauge(app.UIFigure, 'circular');
            app.MeasuredAirspeedGauge.Limits = [0 40];
            app.MeasuredAirspeedGauge.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.MeasuredAirspeedGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.AngleofAttackcircLabel = uilabel(app.UIFigure);
            app.AngleofAttackcircLabel.Text = 'Angle of Attack (°)';
            app.AngleofAttackGauge = uigauge(app.UIFigure, 'semicircular');
            app.AngleofAttackGauge.Limits = [-40 40];
            app.AngleofAttackGauge.MajorTicks = [-45 -35 -25 -15 -5 5 15 25 35 45];
            app.AngleofAttackGauge.MinorTicks = [-45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45];
            app.PortPressureDifferencePaLabel = uilabel(app.UIFigure);
            app.PortPressureDifferencePaLabel.Text = 'α-Port Pressure Difference (Pa)';
            app.AoAPortPressureDifferenceGauge = uigauge(app.UIFigure, 'linear');
            app.AoAPortPressureDifferenceGauge.Limits = [-500 500];
            app.DynamicPressurePaGaugeLabel = uilabel(app.UIFigure);
            app.DynamicPressurePaGaugeLabel.Text = 'Dynamic Pressure (Pa)';
            app.DynamicPressureGauge = uigauge(app.UIFigure, 'linear');
            app.DynamicPressureGauge.Limits = [0 1500];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SerialPortLabel = uilabel(app.UIFigure);
            app.SerialPortLabel.Text = 'Serial Port:';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PressureUnitsDropDownLabel = uilabel(app.UIFigure);
            app.PressureUnitsDropDownLabel.Text = 'Pressure Units';
            app.PressureUnitsDropDown = uidropdown(app.UIFigure);
            app.PressureUnitsDropDown.Items = {'Pascal', 'psi'};
            app.PressureUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PressureUnitsDropDownValueChanged, true);
            app.PressureUnitsDropDown.Value = 'Pascal';
            app.AirspeedUnitsDropDownLabel = uilabel(app.UIFigure);
            app.AirspeedUnitsDropDownLabel.Text = 'Airspeed Units';
            app.AirspeedUnitsDropDown = uidropdown(app.UIFigure);
            app.AirspeedUnitsDropDown.Items = {'m/s', 'ft/s', 'mi/hr', 'kts'};
            app.AirspeedUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @AirspeedUnitsDropDownValueChanged, true);
            app.AirspeedUnitsDropDown.Value = 'm/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TunnelApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end