classdef TunnelApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RefreshButton                 matlab.ui.control.Button
        SerialPortDropDown            matlab.ui.control.DropDown
        SerialPortLabel               matlab.ui.control.Label
        ConnectionLamp                matlab.ui.control.Lamp
        ConnectionLampLabel           matlab.ui.control.Label
        DisconnectButton              matlab.ui.control.Button
        ConnectButton                 matlab.ui.control.Button
        DynamicPressurePaGauge        matlab.ui.control.LinearGauge
        DynamicPressurePaGaugeLabel   matlab.ui.control.Label
        AoAPortPressureDifferencePaGauge  matlab.ui.control.LinearGauge
        AoAPortPressureDifferencePaGaugeLabel  matlab.ui.control.Label
        AngleofAttackGauge            matlab.ui.control.SemicircularGauge
        AngleofAttackcircLabel        matlab.ui.control.Label
        MeasuredAirspeedmsGauge       matlab.ui.control.Gauge
        MeasuredAirspeedmsGaugeLabel  matlab.ui.control.Label
        SpeedSelectionmsKnob          matlab.ui.control.Knob
        SpeedSelectionmsKnobLabel     matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        SETSPEEDButton                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PlotsTab                      matlab.ui.container.Tab
        FreezeButton_aoa              matlab.ui.control.StateButton
        FreezeButton_asp              matlab.ui.control.StateButton
        ClearPlotButton_2             matlab.ui.control.Button
        ClearPlotButton               matlab.ui.control.Button
        AoaPlot                       matlab.ui.control.UIAxes
        AspdPlot                      matlab.ui.control.UIAxes
        HelpTab                       matlab.ui.container.Tab
        HowtoOperateTextArea_2        matlab.ui.control.TextArea
        HowtoOperateTextArea_2Label   matlab.ui.control.Label
        KnownIssuesTextArea           matlab.ui.control.TextArea
        KnownIssuesTextAreaLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        % Defines set of global constants
        TIMER_OBJ;              % Timer that executes readData()
        TIME_INTERVAL = 0.05;   % Seconds. Interval for the TIMER_OBJ
        CONNECTED;              % Flag for whether or not the app is connected to a serial port
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        MAX_VECTOR_LENGTH = 1000;   % Defines the length of uut_speeds and aoa_pres used for plotting
        SERIAL_PORT;        % Serial port object
    end
    
    properties (Access = public)
        uut_speeds = [];    % Vector of airspeed values measured by pitot probe with alpha ports
        uut_pres = [];
        aoa_pres = [];      % Vector of measured pressure differences between alpha ports
        aoa_vec = [];
        % Plot objects
        pltAspdLeft;               
        pltAspdRight;
        pltAoaLeft;               
        pltAoaRight;
    end
   
    
    methods (Access = private)
        
        function readData(app, ~, ~)
            numLinesToRead = 30;
            flush(app.SERIAL_PORT);      % Clear unread messages
            data = zeros(numLinesToRead, 3);
            while app.SERIAL_PORT.NumBytesAvailable < 4    % Wait until a full message is ready
            end
        
            for i = 1:numLinesToRead
                if i == 1
                    useless = readline(app.SERIAL_PORT);   % Dump the first line into an unused variable
                end
                string     = readline(app.SERIAL_PORT);
                data(i, :) = str2double(strsplit(string, "\t"));
                
            end
            % Parse data
            aoa_dat = mean(data(data(:, 1)==1, :), 1);
            ref_dat = mean(data(data(:, 1)==3, :), 1);
            uut_dat = mean(data(data(:, 1)==4, :), 1);
            % Update speed and aoa vectors
            app.uut_speeds      = circshift(app.uut_speeds, -1);
            app.uut_speeds(end) = uut_dat(3);
            app.uut_pres        = circshift(app.uut_pres, -1);
            app.uut_pres(end)   = uut_dat(2);
            app.aoa_pres        = circshift(app.aoa_pres, -1);
            app.aoa_pres(end)   = aoa_dat(2);
            app.aoa_vec         = circshift(app.aoa_vec, -1);
            
            % Update Gauges
            app.DynamicPressurePaGauge.Value  = ref_dat(2);
            app.MeasuredAirspeedmsGauge.Value = ref_dat(3);
            app.AoAPortPressureDifferencePaGauge.Value = aoa_dat(2);
            app.aoa_vec(end) = interpAoa(aoa_dat(2), ref_dat(2));
            app.AngleofAttackGauge.Value = app.aoa_vec(end);

            % Update plots
            if ~app.FreezeButton_asp.Value
            refreshdata(app.pltAspdLeft, 'caller');
            refreshdata(app.pltAspdRight, 'caller');
            end
            if ~app.FreezeButton_aoa.Value
            refreshdata(app.pltAoaLeft, 'caller');
            refreshdata(app.pltAoaRight, 'caller');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize vectors
            app.uut_speeds = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoa_pres = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoa_vec = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uut_pres = zeros(1, app.MAX_VECTOR_LENGTH);
            
            % Initialize the plots
            yyaxis(app.AspdPlot, "left")
            app.pltAspdLeft = plot(app.AspdPlot, 1:app.MAX_VECTOR_LENGTH, app.uut_speeds);
            app.pltAspdLeft.YDataSource = 'app.uut_speeds';
            yyaxis(app.AspdPlot, "right")
            app.pltAspdRight = plot(app.AspdPlot, 1:app.MAX_VECTOR_LENGTH, app.uut_pres);
            app.pltAspdRight.YDataSource = 'app.uut_pres';
            app.AspdPlot.YLabel.String = 'Pressure (Pa)';
            ylim(app.AspdPlot, [0, 1500])
            yticks(app.AspdPlot, 0:250:1500)

            yyaxis(app.AoaPlot, "left")
            title(app.AoaPlot, "UUT α-Port Pressure Difference & Angle of Attack")
            app.pltAoaLeft = plot(app.AoaPlot, 1:app.MAX_VECTOR_LENGTH, app.aoa_pres);
            app.pltAoaLeft.YDataSource = 'app.aoa_pres';
            yyaxis(app.AoaPlot, "right")
            app.pltAoaRight = plot(app.AoaPlot, 1:app.MAX_VECTOR_LENGTH, app.aoa_vec);
            app.pltAoaRight.YDataSource = 'app.aoa_vec';
            app.AoaPlot.YLabel.String = 'Angle of Attack (\circ)';
            ylim(app.AoaPlot, [-40, 40])
            

            for i = [app.pltAspdLeft, app.pltAspdRight, app.pltAoaLeft, app.pltAoaRight]
                lineWidth = 2;
                set(i, 'LineWidth', lineWidth);
            end
            
            % Create serial port list
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;

            % Initialize, but don't start, the timer
            app.TIMER_OBJ = timer;
            app.TIMER_OBJ.TimerFcn = @app.readData;
            app.TIMER_OBJ.ExecutionMode = 'fixedRate';
            app.TIMER_OBJ.Period = app.TIME_INTERVAL;
            app.TIMER_OBJ.BusyMode = 'drop';


        end

        % Button pushed function: SETSPEEDButton
        function SETSPEEDButtonPushed(app, event)
            if app.CONNECTED
                pwrSetting = (app.SpeedSelectionmsKnob.Value - 4.542)/0.3725;
                writeline(app.SERIAL_PORT, num2str(pwrSetting));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.CONNECTED
                writeline(app.SERIAL_PORT, "0");
                app.SpeedSelectionmsKnob.Value = 0;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            portsAvail = serialportlist;
            app.SerialPortDropDown.Items = portsAvail;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.SERIAL_PORT)
                app.SERIAL_PORT = serialport(app.SerialPortDropDown.Value, 9600);
                app.CONNECTED = 1;
                app.ConnectionLamp.Color = app.GREEN_COLOR;
                start(app.TIMER_OBJ)
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.SERIAL_PORT = [];
            app.CONNECTED = 0;
            app.ConnectionLamp.Color = app.RED_COLOR;
            stop(app.TIMER_OBJ);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            app.uut_speeds = zeros(1, app.MAX_VECTOR_LENGTH);
            app.uut_pres = zeros(1, app.MAX_VECTOR_LENGTH);
        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            app.aoa_pres = zeros(1, app.MAX_VECTOR_LENGTH);
            app.aoa_vec = zeros(1, app.MAX_VECTOR_LENGTH);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.AspdPlot = uiaxes(app.PlotsTab);
            title(app.AspdPlot, 'UUT Airspeed & Dynamic Pressure')
            ylabel(app.AspdPlot, 'Airspeed (m/s)')
            zlabel(app.AspdPlot, 'Z')
            app.AspdPlot.XLim = [0 1000];
            app.AspdPlot.YLim = [0 45];
            app.AspdPlot.XTickLabel = '';
            app.AoaPlot = uiaxes(app.PlotsTab);
            title(app.AoaPlot, 'UUT \alpha-Port Pressure Difference')
            ylabel(app.AoaPlot, 'Pressure (Pa)')
            zlabel(app.AoaPlot, 'Z')
            app.AoaPlot.XLim = [0 1000];
            app.AoaPlot.YLim = [-300 300];
            app.AoaPlot.XTickLabel = '';
            app.ClearPlotButton = uibutton(app.PlotsTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ClearPlotButton_2 = uibutton(app.PlotsTab, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.FreezeButton_asp = uibutton(app.PlotsTab, 'state');
            app.FreezeButton_asp.Text = 'Freeze';
            app.FreezeButton_aoa = uibutton(app.PlotsTab, 'state');
            app.FreezeButton_aoa.Text = 'Freeze';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.KnownIssuesTextAreaLabel = uilabel(app.HelpTab);
            app.KnownIssuesTextAreaLabel.Text = 'Known Issues:';
            app.KnownIssuesTextArea = uitextarea(app.HelpTab);
            app.KnownIssuesTextArea.Editable = 'off';
            app.KnownIssuesTextArea.Value = {'If the incorrect serial port is chosen, then the app may become frozen and the process will need to be killed in Task Manager.'};
            app.HowtoOperateTextArea_2Label = uilabel(app.HelpTab);
            app.HowtoOperateTextArea_2Label.Text = 'How to Operate:';
            app.HowtoOperateTextArea_2 = uitextarea(app.HelpTab);
            app.HowtoOperateTextArea_2.Editable = 'off';
            app.HowtoOperateTextArea_2.Value = {'1. Once the USB cable from the wind tunnel is connected to your computer, select the serial port from the '; '    dropdown. If you connected the USB after opening the application, then hit refresh to fetch the list of serial ports.'; '2. Hit "Connect", and wait for the green indicator to illuminate. The fan may ramp up to a speed of about 20 '; '    m/s, then it will return to zero.'; '3. To select a speed, click on the desired speed on the selection knob and press "SET SPEED". The '; '    reference speed should then be displayed on the "Measured Airspeed" gauge. '; ''; 'The angle of attack readings are accurate between 25 and 35 m/s.'; ''};
            app.SETSPEEDButton = uibutton(app.UIFigure, 'push');
            app.SETSPEEDButton.ButtonPushedFcn = createCallbackFcn(app, @SETSPEEDButtonPushed, true);
            app.SETSPEEDButton.Text = 'SET SPEED';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SpeedSelectionmsKnobLabel = uilabel(app.UIFigure);
            app.SpeedSelectionmsKnobLabel.Text = 'Speed Selection (m/s)';
            app.SpeedSelectionmsKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedSelectionmsKnob.Limits = [0 45];
            app.SpeedSelectionmsKnob.MajorTicks = [0 5 10 15 20 25 30 35];
            app.MeasuredAirspeedmsGaugeLabel = uilabel(app.UIFigure);
            app.MeasuredAirspeedmsGaugeLabel.Text = 'Measured Airspeed (m/s)';
            app.MeasuredAirspeedmsGauge = uigauge(app.UIFigure, 'circular');
            app.MeasuredAirspeedmsGauge.Limits = [0 40];
            app.MeasuredAirspeedmsGauge.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.MeasuredAirspeedmsGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.AngleofAttackcircLabel = uilabel(app.UIFigure);
            app.AngleofAttackcircLabel.Text = 'Angle of Attack (°)';
            app.AngleofAttackGauge = uigauge(app.UIFigure, 'semicircular');
            app.AngleofAttackGauge.Limits = [-40 40];
            app.AngleofAttackGauge.MajorTicks = [-45 -35 -25 -15 -5 5 15 25 35 45];
            app.AngleofAttackGauge.MinorTicks = [-45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45];
            app.AoAPortPressureDifferencePaGaugeLabel = uilabel(app.UIFigure);
            app.AoAPortPressureDifferencePaGaugeLabel.Text = 'AoA Port Pressure Difference (Pa)';
            app.AoAPortPressureDifferencePaGauge = uigauge(app.UIFigure, 'linear');
            app.AoAPortPressureDifferencePaGauge.Limits = [-500 500];
            app.DynamicPressurePaGaugeLabel = uilabel(app.UIFigure);
            app.DynamicPressurePaGaugeLabel.Text = 'Dynamic Pressure (Pa)';
            app.DynamicPressurePaGauge = uigauge(app.UIFigure, 'linear');
            app.DynamicPressurePaGauge.Limits = [0 1500];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SerialPortLabel = uilabel(app.UIFigure);
            app.SerialPortLabel.Text = 'Serial Port:';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TunnelApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end